classdef app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        GridLayout                     matlab.ui.container.GridLayout
        LeftPanel                      matlab.ui.container.Panel
        Label_9                        matlab.ui.control.Label
        Label_10                       matlab.ui.control.Label
        ConfiguraesdofiltroLabel       matlab.ui.control.Label
        ConfiguraesdajanelaLabel       matlab.ui.control.Label
        TipoDropDownLabel              matlab.ui.control.Label
        tipoFiltro                     matlab.ui.control.DropDown
        tipoJanela                     matlab.ui.control.DropDown
        FreqdepassagemHzLabel          matlab.ui.control.Label
        freqdepassagem                 matlab.ui.control.NumericEditField
        GerarButton                    matlab.ui.control.Button
        FreqdeamostHzLabel             matlab.ui.control.Label
        freqAmostragem                 matlab.ui.control.NumericEditField
        SelecaoautomaticadejanelaCheckBox  matlab.ui.control.CheckBox
        FreqderejeioHzLabel            matlab.ui.control.Label
        freqderejeicao                 matlab.ui.control.NumericEditField
        TipoFiltroLabel                matlab.ui.control.Label
        RipplemxnabandadepassagemdBLabel  matlab.ui.control.Label
        atenuacaoBandaPassagem         matlab.ui.control.NumericEditField
        AtenuaonabandaderejeiodBLabel  matlab.ui.control.Label
        atenuacaoBandaRejeicao         matlab.ui.control.NumericEditField
        RightPanel                     matlab.ui.container.Panel
        FiltroLabel                    matlab.ui.control.Label
        JanelaLabel                    matlab.ui.control.Label
        TabGroup                       matlab.ui.container.TabGroup
        MagnitudeTab                   matlab.ui.container.Tab
        MagnitudeFiltro                matlab.ui.control.UIAxes
        FaseTab                        matlab.ui.container.Tab
        FaseFiltro                     matlab.ui.control.UIAxes
        ResultadoLabel                 matlab.ui.control.Label
        LarguradajanelaLabel           matlab.ui.control.Label
        larguraJanela                  matlab.ui.control.NumericEditField
        JanelautilizadaLabel           matlab.ui.control.Label
        JanelaUtilizadaText            matlab.ui.control.EditField
        CoeficientesdofiltroLabel      matlab.ui.control.Label
        OrdemEditFieldLabel            matlab.ui.control.Label
        ordemFiltro                    matlab.ui.control.NumericEditField
        TabGroup_2                     matlab.ui.container.TabGroup
        MagnitudeTab_2                 matlab.ui.container.Tab
        MagnitudeJanela                matlab.ui.control.UIAxes
        FaseTab_2                      matlab.ui.container.Tab
        FaseJanela                     matlab.ui.control.UIAxes
        coefsFiltro                    matlab.ui.control.TextArea
        HTML                           matlab.ui.control.HTML
        HTML_2                         matlab.ui.control.HTML
        HTML_3                         matlab.ui.control.HTML
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = private)
        RETANGULAR_STOPBATTENUATION = 21; % Atenuação da jan. retangular na banda de rejeição  (em dB)
        BARLETT_STOPBATTENUATION = 25;    % Atenuação da jan. de Barlett na banda de rejeição  (em dB)
        HANN_STOPBATTENUATION = 44;       % Atenuação da jan. de Hann na banda de rejeição     (em dB)
        HAMMING_STOPBATTENUATION = 53;    % Atenuação da jan. de Hamming na banda de rejeição  (em dB)
        BLACKMAN_STOPBATTENUATION = 74;   % Atenuação da jan. de Blackman na banda de rejeição (em dB)
        
        RETANGULAR_PASSBRIPPLE = 0.7416;  % Atenuação da jan. retangular na banda de passagem  (em dB)
        BARLETT_PASSBRIPPLE = 0.4237;     % Atenuação da jan. de Barlett na banda de passagem  (em dB)
        HANN_PASSBRIPPLE = 0.0546;        % Atenuação da jan. de Hann na banda de passagem     (em dB)
        HAMMING_PASSBRIPPLE = 0.0194;     % Atenuação da jan. de Hamming na banda de passagem  (em dB)
        BLACKMAN_PASSBRIPPLE = 0.0017;    % Atenuação da jan. de Blackman na banda de passagem (em dB)
    end
    
    methods (Access = private)
        
        % Plota o espectro de magnitude do sinal informado
        function magPlot(~, signal, fftLength, plotSpot)
            A = fft(signal, fftLength);
            mag = abs(fftshift(A));
            freq = linspace(-0.5, 0.5, length(A));
            response = 20 * log10(mag);
            plot(plotSpot, freq, response);
        end
        
        % Plota o espectro de fase do sinal informado
        function phasePlot(~, signal, fftLength, plotSpot)
            A = fft(signal, fftLength);
            phase = angle(A);
            plot(plotSpot, phase);
        end    
        
        function winSize = windowSize(~, idWindow, fpass, fstop, fsamp)
            wpass = 2 * pi * fpass;
            wstop = 2 * pi * fstop;
            wsamp = 2 * pi * fsamp;
            delta_w = (wstop - wpass)/wsamp;
            if idWindow == 1
                aux = ceil(((4 * pi) - delta_w) / delta_w);
            elseif idWindow == 2 || idWindow == 3 || idWindow == 4
                aux = ceil((8 * pi) / delta_w);
            else
                aux = ceil((12 * pi) / delta_w);
            end
            % winSize = 2*aux + 1;            
            winSize = aux;
        end
        
        function window = getWindow(app, idWindow, windowSize)
            if idWindow == 1
                window = rectwin(windowSize);
                app.JanelaUtilizadaText.Value = "Retangular";
            elseif idWindow == 2
                window = bartlett(windowSize);
                app.JanelaUtilizadaText.Value = "Bartlett";
            elseif idWindow == 3
                window = hann(windowSize);
                app.JanelaUtilizadaText.Value = "Hann";
            elseif idWindow == 4
                window = hamming(windowSize);
                app.JanelaUtilizadaText.Value = "Hamming";
            else
                window = blackman(windowSize);
                app.JanelaUtilizadaText.Value = "Blackman";
            end
        end
        
        function refreshPossibleWindows(app)
            if app.atenuacaoBandaPassagem.Value == 0 || app.atenuacaoBandaRejeicao.Value == 0
                    app.tipoJanela.Items = ["Retangular", "Barlett", "Hann", "Hamming", "Blackman"];
                else
                    aux = 1;
                    if  app.RETANGULAR_STOPBATTENUATION  >= app.atenuacaoBandaRejeicao.Value...
                        && app.RETANGULAR_PASSBRIPPLE    <= app.atenuacaoBandaPassagem.Value
                            possibleWindows(aux) = "Retangular";
                            aux = aux + 1;
                    end
                    if app.BARLETT_STOPBATTENUATION   >= app.atenuacaoBandaRejeicao.Value...
                        && app.BARLETT_PASSBRIPPLE    <= app.atenuacaoBandaPassagem.Value
                            possibleWindows(aux) = "Bartlett";
                            aux = aux + 1;
                    end
                    if app.HANN_STOPBATTENUATION      >= app.atenuacaoBandaRejeicao.Value...
                        && app.HANN_PASSBRIPPLE       <= app.atenuacaoBandaPassagem.Value
                            possibleWindows(aux) = "Hann";
                            aux = aux + 1;
                    end
                    if app.HAMMING_STOPBATTENUATION   >= app.atenuacaoBandaRejeicao.Value...
                        && app.HAMMING_PASSBRIPPLE    <= app.atenuacaoBandaPassagem.Value
                            possibleWindows(aux) = "Hamming";
                            aux = aux + 1;
                    end
                    if app.BLACKMAN_STOPBATTENUATION  >= app.atenuacaoBandaRejeicao.Value...
                        && app.BLACKMAN_PASSBRIPPLE   <= app.atenuacaoBandaPassagem.Value
                            possibleWindows(aux) = "Blackman";
                    end
                    app.tipoJanela.Items = possibleWindows;
                    app.tipoJanela.Value = possibleWindows(1);
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: GerarButton
        function GerarButtonPushed(app, event)
            if app.tipoFiltro.Value == "Passa-baixas"
                tipoF = "low";
            elseif app.tipoFiltro.Value == "Passa-altas"
                tipoF = "high";
            elseif app.tipoFiltro.Value == "Rejeita faixa"
                tipoF = "stop";
            else
                tipoF = "bandpass";
            end
            
            if app.SelecaoautomaticadejanelaCheckBox.Value == true
                windowPossibilities = [...
                    app.RETANGULAR_STOPBATTENUATION  >= app.atenuacaoBandaRejeicao.Value...
                        & app.RETANGULAR_PASSBRIPPLE <= app.atenuacaoBandaPassagem.Value ...
                    app.BARLETT_STOPBATTENUATION     >= app.atenuacaoBandaRejeicao.Value...
                        & app.BARLETT_PASSBRIPPLE    <= app.atenuacaoBandaPassagem.Value ...
                    app.HANN_STOPBATTENUATION        >= app.atenuacaoBandaRejeicao.Value...
                        & app.HANN_PASSBRIPPLE       <= app.atenuacaoBandaPassagem.Value ...
                    app.HAMMING_STOPBATTENUATION     >= app.atenuacaoBandaRejeicao.Value...
                        & app.HAMMING_PASSBRIPPLE    <= app.atenuacaoBandaPassagem.Value ...
                    app.BLACKMAN_STOPBATTENUATION    >= app.atenuacaoBandaRejeicao.Value...
                        & app.BLACKMAN_PASSBRIPPLE   <= app.atenuacaoBandaPassagem.Value
                ];
                aux = 1;
                for i = 1 : length(windowPossibilities)
                    if windowPossibilities(i)
                        filterOrder(aux) = app.windowSize(i, ...
                            app.freqdepassagem.Value, app.freqderejeicao.Value, ...
                            app.freqAmostragem.Value) - 1;
                        aux = aux + 1;
                    end
                end
                [~, index] = min(filterOrder);
                idx = find(windowPossibilities == true, index, 'first');
                window = getWindow(app, idx, filterOrder(index) + 1);
                app.larguraJanela.Value = filterOrder(index) + 1;
                filtOrd = filterOrder(index);
            else
                if app.tipoJanela.Value == "Retangular"
                    idJan = 1;
                elseif app.tipoJanela.Value == "Bartlett"
                    idJan = 2;
                elseif app.tipoJanela.Value == "Hann"
                    idJan = 3;
                elseif app.tipoJanela.Value == "Hamming"
                    idJan = 4;
                else
                    idJan = 5;
                end
                windowSize = app.windowSize(idJan, ...
                            app.freqdepassagem.Value, app.freqderejeicao.Value, ...
                            app.freqAmostragem.Value);
                window = getWindow(app, idJan, windowSize);
                filtOrd = windowSize - 1;
            end
            
            magPlot(app, window, 8192, app.MagnitudeJanela);
            phasePlot(app, window, 8192, app.FaseJanela);
            % wvtool(window);
            
            fcutoff = (app.freqdepassagem.Value + app.freqderejeicao.Value) / 2;
            wcutoff = 2 * pi * fcutoff;
            normalized_wcutoff = wcutoff / app.freqAmostragem.Value;
            coef = fir1(filtOrd, normalized_wcutoff/pi, tipoF, window);
            
            app.coefsFiltro.Value = num2str(coef);
            
            app.ordemFiltro.Value = filtOrd;
            
            magPlot(app, coef, 8192, app.MagnitudeFiltro);
            phasePlot(app, coef, 8192, app.FaseFiltro);
            %fvtool(coef);
        end

        % Value changed function: SelecaoautomaticadejanelaCheckBox
        function SelecaoautomaticadejanelaCheckBoxValueChanged(app, event)
            value = app.SelecaoautomaticadejanelaCheckBox.Value;
            if value == true
                app.tipoJanela.Enable = false;
                app.tipoJanela.Visible = false;
                app.TipoFiltroLabel.Visible = false;
                app.JanelautilizadaLabel.Visible = true;
                app.JanelaUtilizadaText.Visible = true;
            else
                if app.atenuacaoBandaPassagem.Value == 0 || app.atenuacaoBandaRejeicao.Value == 0
                    app.tipoJanela.Items = ["Retangular", "Barlett", "Hann", "Hamming", "Blackman"];
                else
                    aux = 1;
                    if  app.RETANGULAR_STOPBATTENUATION  >= app.atenuacaoBandaRejeicao.Value...
                        && app.RETANGULAR_PASSBRIPPLE    <= app.atenuacaoBandaPassagem.Value
                            possibleWindows(aux) = "Retangular";
                            aux = aux + 1;
                    end
                    if app.BARLETT_STOPBATTENUATION   >= app.atenuacaoBandaRejeicao.Value...
                        && app.BARLETT_PASSBRIPPLE    <= app.atenuacaoBandaPassagem.Value
                            possibleWindows(aux) = "Bartlett";
                            aux = aux + 1;
                    end
                    if app.HANN_STOPBATTENUATION      >= app.atenuacaoBandaRejeicao.Value...
                        && app.HANN_PASSBRIPPLE       <= app.atenuacaoBandaPassagem.Value
                            possibleWindows(aux) = "Hann";
                            aux = aux + 1;
                    end
                    if app.HAMMING_STOPBATTENUATION   >= app.atenuacaoBandaRejeicao.Value...
                        && app.HAMMING_PASSBRIPPLE    <= app.atenuacaoBandaPassagem.Value
                            possibleWindows(aux) = "Hamming";
                            aux = aux + 1;
                    end
                    if app.BLACKMAN_STOPBATTENUATION  >= app.atenuacaoBandaRejeicao.Value...
                        && app.BLACKMAN_PASSBRIPPLE   <= app.atenuacaoBandaPassagem.Value
                            possibleWindows(aux) = "Blackman";
                    end
                    app.tipoJanela.Items = possibleWindows;
                    app.tipoJanela.Value = possibleWindows(1);
                end
                app.tipoJanela.Enable = true;
                app.tipoJanela.Visible = true;
                app.TipoFiltroLabel.Visible = true;
                app.JanelautilizadaLabel.Visible = false;
                app.JanelaUtilizadaText.Visible = false;
            end
        end

        % Value changed function: tipoFiltro
        function tipoFiltroValueChanged(app, event)
            value = app.tipoFiltro.Value;
            if value == "Passa-baixas"
                
            end
        end

        % Value changed function: freqdepassagem
        function freqdepassagemValueChanged(app, event)
            value = app.freqdepassagem.Value;
            app.freqAmostragem.Limits = [2*value, Inf];
        end

        % Value changed function: atenuacaoBandaPassagem
        function atenuacaoBandaPassagemValueChanged(app, event)
            if app.SelecaoautomaticadejanelaCheckBox.Value == false
                refreshPossibleWindows(app);
            end
        end

        % Value changed function: atenuacaoBandaRejeicao
        function atenuacaoBandaRejeicaoValueChanged(app, event)
            if app.SelecaoautomaticadejanelaCheckBox.Value == false
                refreshPossibleWindows(app);
            end
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {479, 479};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {295, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.Label_9 = uilabel(app.LeftPanel);
            app.Label_9.Text = '__________________________________________';
            app.Label_10 = uilabel(app.LeftPanel);
            app.Label_10.Text = '__________________________________________';
            app.ConfiguraesdofiltroLabel = uilabel(app.LeftPanel);
            app.ConfiguraesdofiltroLabel.Text = 'Configurações do filtro';
            app.ConfiguraesdajanelaLabel = uilabel(app.LeftPanel);
            app.ConfiguraesdajanelaLabel.Text = 'Configurações da janela';
            app.TipoDropDownLabel = uilabel(app.LeftPanel);
            app.TipoDropDownLabel.Text = 'Tipo';
            app.tipoFiltro = uidropdown(app.LeftPanel);
            app.tipoFiltro.Items = {'Passa-baixas', 'Passa-altas', 'Passa-faixas', 'Rejeita faixa'};
            app.tipoFiltro.ValueChangedFcn = createCallbackFcn(app, @tipoFiltroValueChanged, true);
            app.tipoFiltro.Value = 'Passa-baixas';
            app.tipoJanela = uidropdown(app.LeftPanel);
            app.tipoJanela.Items = {'Retangular', 'Bartlett', 'Hann', 'Hamming', 'Blackman'};
            app.tipoJanela.Enable = 'off';
            app.tipoJanela.Visible = 'off';
            app.tipoJanela.Value = 'Retangular';
            app.FreqdepassagemHzLabel = uilabel(app.LeftPanel);
            app.FreqdepassagemHzLabel.Text = 'Freq. de passagem (Hz)';
            app.freqdepassagem = uieditfield(app.LeftPanel, 'numeric');
            app.freqdepassagem.ValueChangedFcn = createCallbackFcn(app, @freqdepassagemValueChanged, true);
            app.freqdepassagem.Value = 1000;
            app.GerarButton = uibutton(app.LeftPanel, 'push');
            app.GerarButton.ButtonPushedFcn = createCallbackFcn(app, @GerarButtonPushed, true);
            app.GerarButton.Text = 'Gerar';
            app.FreqdeamostHzLabel = uilabel(app.LeftPanel);
            app.FreqdeamostHzLabel.Text = 'Freq. de amost. (Hz)';
            app.freqAmostragem = uieditfield(app.LeftPanel, 'numeric');
            app.freqAmostragem.ValueDisplayFormat = '%.0f';
            app.freqAmostragem.Value = 8000;
            app.SelecaoautomaticadejanelaCheckBox = uicheckbox(app.LeftPanel);
            app.SelecaoautomaticadejanelaCheckBox.ValueChangedFcn = createCallbackFcn(app, @SelecaoautomaticadejanelaCheckBoxValueChanged, true);
            app.SelecaoautomaticadejanelaCheckBox.Text = 'Seleção automática de janela';
            app.SelecaoautomaticadejanelaCheckBox.Value = true;
            app.FreqderejeioHzLabel = uilabel(app.LeftPanel);
            app.FreqderejeioHzLabel.Text = 'Freq. de rejeição (Hz)';
            app.freqderejeicao = uieditfield(app.LeftPanel, 'numeric');
            app.freqderejeicao.Value = 3000;
            app.TipoFiltroLabel = uilabel(app.LeftPanel);
            app.TipoFiltroLabel.Visible = 'off';
            app.TipoFiltroLabel.Text = 'Tipo';
            app.RipplemxnabandadepassagemdBLabel = uilabel(app.LeftPanel);
            app.RipplemxnabandadepassagemdBLabel.Text = {'Ripple máx. na banda'; 'de passagem (dB)'};
            app.atenuacaoBandaPassagem = uieditfield(app.LeftPanel, 'numeric');
            app.atenuacaoBandaPassagem.ValueChangedFcn = createCallbackFcn(app, @atenuacaoBandaPassagemValueChanged, true);
            app.atenuacaoBandaPassagem.Value = 0.1;
            app.AtenuaonabandaderejeiodBLabel = uilabel(app.LeftPanel);
            app.AtenuaonabandaderejeiodBLabel.Text = {'Atenuação na banda'; 'de rejeição (dB)'};
            app.atenuacaoBandaRejeicao = uieditfield(app.LeftPanel, 'numeric');
            app.atenuacaoBandaRejeicao.ValueDisplayFormat = '%.0f';
            app.atenuacaoBandaRejeicao.ValueChangedFcn = createCallbackFcn(app, @atenuacaoBandaRejeicaoValueChanged, true);
            app.atenuacaoBandaRejeicao.Value = 50;
            app.RightPanel = uipanel(app.GridLayout);
            app.FiltroLabel = uilabel(app.RightPanel);
            app.FiltroLabel.Text = 'Filtro';
            app.JanelaLabel = uilabel(app.RightPanel);
            app.JanelaLabel.Text = 'Janela';
            app.TabGroup = uitabgroup(app.RightPanel);
            app.MagnitudeTab = uitab(app.TabGroup);
            app.MagnitudeTab.Title = 'Magnitude';
            app.MagnitudeFiltro = uiaxes(app.MagnitudeTab);
            title(app.MagnitudeFiltro, '')
            xlabel(app.MagnitudeFiltro, 'Frequência')
            ylabel(app.MagnitudeFiltro, 'Amplitude')
            app.FaseTab = uitab(app.TabGroup);
            app.FaseTab.Title = 'Fase';
            app.FaseFiltro = uiaxes(app.FaseTab);
            title(app.FaseFiltro, '')
            xlabel(app.FaseFiltro, 'X')
            ylabel(app.FaseFiltro, 'Y')
            app.ResultadoLabel = uilabel(app.RightPanel);
            app.ResultadoLabel.Text = 'Resultado';
            app.LarguradajanelaLabel = uilabel(app.RightPanel);
            app.LarguradajanelaLabel.Text = 'Largura da janela';
            app.larguraJanela = uieditfield(app.RightPanel, 'numeric');
            app.larguraJanela.Editable = 'off';
            app.JanelautilizadaLabel = uilabel(app.RightPanel);
            app.JanelautilizadaLabel.Text = 'Janela utilizada';
            app.JanelaUtilizadaText = uieditfield(app.RightPanel, 'text');
            app.JanelaUtilizadaText.Editable = 'off';
            app.CoeficientesdofiltroLabel = uilabel(app.RightPanel);
            app.CoeficientesdofiltroLabel.Text = 'Coeficientes do filtro';
            app.OrdemEditFieldLabel = uilabel(app.RightPanel);
            app.OrdemEditFieldLabel.Text = 'Ordem do filtro';
            app.ordemFiltro = uieditfield(app.RightPanel, 'numeric');
            app.ordemFiltro.Editable = 'off';
            app.TabGroup_2 = uitabgroup(app.RightPanel);
            app.MagnitudeTab_2 = uitab(app.TabGroup_2);
            app.MagnitudeTab_2.Title = 'Magnitude';
            app.MagnitudeJanela = uiaxes(app.MagnitudeTab_2);
            title(app.MagnitudeJanela, '')
            xlabel(app.MagnitudeJanela, 'Frequência')
            ylabel(app.MagnitudeJanela, 'Amplitude')
            app.FaseTab_2 = uitab(app.TabGroup_2);
            app.FaseTab_2.Title = 'Fase';
            app.FaseJanela = uiaxes(app.FaseTab_2);
            title(app.FaseJanela, '')
            xlabel(app.FaseJanela, 'X')
            ylabel(app.FaseJanela, 'Y')
            app.coefsFiltro = uitextarea(app.RightPanel);
            app.coefsFiltro.Editable = 'off';
            app.HTML = uihtml(app.RightPanel);
            app.HTML.HTMLSource = '<hr style="height:1.5px;border-width:0;color:rgb(240,140,201);background-color:rgb(240,140,201)"></hr>';
            app.HTML_2 = uihtml(app.RightPanel);
            app.HTML_2.HTMLSource = '<hr style="height:1.5px;border-width:0;color:rgb(240,140,201);background-color:rgb(240,140,201)"></hr>';
            app.HTML_3 = uihtml(app.RightPanel);
            app.HTML_3.HTMLSource = '<hr style="height:1.5px;border-width:0;color:rgb(240,140,201);background-color:rgb(240,140,201)"></hr>';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end